classdef ShortestPath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShortestPathCalculatorUIFigure  matlab.ui.Figure
        Status                          matlab.ui.control.TextArea
        InputParametersPanel            matlab.ui.container.Panel
        SelectthegraphfileLabel         matlab.ui.control.Label
        GraphBrowse                     matlab.ui.control.Button
        InputmodeDropDownLabel          matlab.ui.control.Label
        InputmodeDropDown               matlab.ui.control.DropDown
        RootNodeLabel                   matlab.ui.control.Label
        RootNode                        matlab.ui.control.DropDown
        ManualStartNode                 matlab.ui.control.Label
        ManualStart                     matlab.ui.control.DropDown
        ManualEndNode                   matlab.ui.control.Label
        ManualEnd                       matlab.ui.control.DropDown
        OutputBtn                       matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        STGraphPlot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        path
        nodeCount
        plotG
        costMatrix
        graphStatus
        graphReadStatus
        nodes
        inputStatus
        pathGenStatus
        spanPlot
        SPTStatus
        dropdownNames
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            movegui(app.ShortestPathCalculatorUIFigure,"center");
        end

        % Button pushed function: GraphBrowse
        function GraphBrowseButtonPushed(app, event)
            % Disable buttons, dropdowns, and hide labels initially
            app.InputmodeDropDown.Enable = false;
            app.InputmodeDropDown.Value = 'Select one';
            app.ManualStartNode.Visible = false;
            app.ManualStart.Visible = false;
            app.ManualStart.Enable = false;
            app.ManualEndNode.Visible = false;
            app.ManualEnd.Visible = false;
            app.ManualEnd.Enable = false;
            app.RootNode.Enable = false;
            app.RootNode.Items = {'0'};
            app.OutputBtn.Enable = false;
            
            % Prompt for graph file named 'Graph.dat'
            [file,app.path] = uigetfile('Graph.dat');

            % If file is not found, fail message is displayed in
            % status window
            if (isequal(file,0) || ~isequal(file, 'Graph.dat'))
                plot(app.STGraphPlot,0);
                app.graphStatus = {'No file selected!'};
                app.Status.Value = app.graphStatus;
                app.RootNode.Items = {'0'};
                app.RootNode.Value = '0';
                app.RootNode.Enable = false;
            % If found, graph file is read, extracting start/end
            % nodes and cost matrix
            else
                app.InputmodeDropDown.Enable = true;
                src = [];
                dest = [];
                weight = [];
                names = [];
                app.costMatrix = [];

                % Status window update after successful read
                app.graphStatus = {['User selected ', fullfile(app.path,file)]};
                app.graphReadStatus  = {['Reading ', file, '...']};
                app.Status.Value = [app.graphStatus; app.graphReadStatus];
                fid = fopen(fullfile(app.path,file), 'r');
               
                % Cost matrix generator: Creates an n-by-n matrix of
                % costs for every single node 
                app.nodeCount = str2double(fgetl(fid));
                for i = 1:app.nodeCount
                    nodeValues = fgetl(fid);    
                    if (~ischar(nodeValues))
                        break; 
                    else
                        rowCost = [];
                        for j = 1:app.nodeCount
                            % If node is between 1-9, the cost location 
                            % is 2*(node value) of the current row:
                            if (j < 10)
                                currCost = str2double(nodeValues(j*2));
                            % For node values >= 10: Offset values are
                            % calculated based on length of current
                            % node. (Tested for values up to 1,000,000
                            % nodes)
                            else
                                len = length(num2str(j));
                                offset1 = num2str(ones(1,len-1) * 8);
                                offset1 = str2double(strcat(num2str(len-1),...
                                    offset1(~isspace(offset1))));
                                offset2 = num2str(ones(1,len-1) * 9);
                                offset2 = str2double(offset2(~isspace(offset2)));
                                currCost = str2double(nodeValues(offset1 + ...
                                    (j-offset2)*(len+1)));
                            end
                            % Update corresponding arrays if cost is
                            % non-zero
                            if (currCost ~= 0)
                                src = [src, i];
                                dest = [dest, j];
                                weight = [weight, currCost];
                            end
                            rowCost = [rowCost, currCost];
                        end 
                        % Update names and cost matrix after each row
                        % generation
                        names = [names; i];
                        app.costMatrix = [app.costMatrix; rowCost];
                        error = 0;
                    end 
                end
                % If no error is detected, dropdown boxes are
                % populated with node values from the graph
                if (error ~= 1)
                    fclose(fid);
                    names = string(names);
                    app.dropdownNames = ["0"; names];
                    names = cellstr(names')';
                    app.RootNode.Items = app.dropdownNames;
                    app.ManualStart.Items = app.dropdownNames;
                    app.ManualEnd.Items = app.dropdownNames;
                    % Displays a directed graph based on the inputs 
                    app.plotG = digraph(src,dest,weight,names);
                    app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                        app.plotG.Edges.Weight);
                end
            end

            % Deletes input/output files that might have been
            % generated during previous runs
            inpfile = strcat(app.path,'Input.dat');
            outfile = strcat(app.path,'Output.dat');
            if (isequal(exist(inpfile, 'file'), 2))
                delete(inpfile);
            end
            if (isequal(exist(outfile, 'file'), 2))
                delete(outfile);
            end

        end

        % Value changed function: InputmodeDropDown
        function InputmodeDropDownValueChanged(app, event)
            % Reset the graph when mode is changed
            app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                app.plotG.Edges.Weight);
            
            value = app.InputmodeDropDown.Value;
            % -------------------------------------------------
            % Auto mode: Calculates shortest paths from one node
            % to another for all given nodes 
            % -------------------------------------------------
            if (strcmp(value, 'Auto'))
                % Update app features and status
                app.RootNode.Visible = true;
                app.RootNodeLabel.Visible = true;
                app.RootNode.Items = app.dropdownNames;
                app.ManualStartNode.Visible = false;
                app.ManualStart.Visible = false;
                app.ManualStart.Enable = false;
                app.ManualStart.Value = '0';
                app.ManualEndNode.Visible = false;
                app.ManualEnd.Visible = false;
                app.ManualEnd.Enable = false;
                app.ManualEnd.Value = '0';
                app.inputStatus = {'Input mode is set to Auto. '};
                app.Status.Value = [app.Status.Value; app.inputStatus];

                % Input file is generated consisting of all possible
                % node combinations
                inpId = fopen(fullfile(app.path,'Input.dat'), 'w');
                for i = 1:app.nodeCount
                    for j = 1:app.nodeCount
                        if (j ~= i)
                            fprintf(inpId, '%s\n%s\nFF\n', ...
                                num2str(i), num2str(j));
                        end
                    end
                end
                fprintf(inpId, '0\n');
                fclose(inpId);

                % Update status after input file gneeration
                app.inputStatus = {['Input.dat generation completed. ', ...
                    'Calculating shortest paths...']};
                app.Status.Value = [app.Status.Value; app.inputStatus];

                % Open I/O files for read and write
                inpId = fopen(fullfile(app.path,'Input.dat'), 'r');
                outId = fopen(fullfile(app.path,'Output.dat'), 'w');

                % Read the input file 'Input.dat' until final line of
                % 0 is detected
                startNode = (fgetl(inpId));
                while (startNode ~= "0")
                    endNode = (fgetl(inpId));
                    if (strcmp(fgetl(inpId), 'FF'))
                        % For each node pair, shortest path is
                        % calculated using the Dijkstra algorithm
                        % function, then written to the output file
                        startNode = str2double(startNode);
                        endNode = str2double(endNode);
                        [dist, SPT] = Dijkstra(app.costMatrix, startNode, endNode);
                        fprintf(outId, '%d\n', dist);
                        fprintf(outId, '%d\n', SPT);
                        fprintf(outId, 'FFFF\n');
                        startNode = (fgetl(inpId));
                    end
                end
                fprintf(outId, '0\n');
                fclose(inpId);
                fclose(outId);
                
                % Update status after generating the output file
                app.pathGenStatus = {['Output.dat generated. Click ', ...
                    '''Open Results'' button to see paths.']};
                app.Status.Value = [app.Status.Value; app.pathGenStatus];
                
                % Output button and SPT root node dropdown are enabled
                app.OutputBtn.Enable = true;                
                app.RootNode.Enable = true;
            % -------------------------------------------------
            % Manual mode: Calculates shortest paths from one node
            % to another for a single specified node
            % -------------------------------------------------            
            elseif (strcmp(value,'Manual'))
                % Update app features and status
                app.OutputBtn.Enable = false;
                app.ManualStartNode.Visible = true;
                app.ManualStart.Visible = true;
                app.ManualEndNode.Visible = true;
                app.ManualEnd.Visible = true;
                app.ManualStart.Enable = true;
                app.ManualEnd.Enable = true;
                app.ManualStart.Value = '0';
                app.ManualEnd.Value = '0';
                app.RootNode.Enable = false;
                app.RootNode.Items = {'0'};
                app.RootNode.Value = '0';                 
                app.RootNode.Visible = false;
                app.RootNodeLabel.Visible = false;
                app.inputStatus = {['Input mode is set to Manual. ', ...
                   'Please select starting node: ']};
                app.Status.Value = [app.Status.Value; app.inputStatus];
            % Other: all features are cleared and disabled
            else
                app.ManualStartNode.Visible = false;
                app.ManualStart.Visible = false;
                app.ManualStart.Enable = false;
                app.ManualStart.Value = '0';
                app.ManualEndNode.Visible = false;
                app.ManualEnd.Visible = false;
                app.ManualEnd.Enable = false;
                app.ManualEnd.Value = '0';
                app.OutputBtn.Enable = false;
                app.RootNode.Enable = false;
                app.RootNode.Visible = false;
                app.RootNodeLabel.Visible = false;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: OutputBtn
        function OutputBtnPushed(app, event)
            open(fullfile(app.path,'Output.dat'));
        end

        % Value changed function: ManualStart
        function ManualStartValueChanged(app, event)
            value = app.ManualStart.Value;
            % Checks if start node is not equal to 0
            if (str2double(value) ~= 0)
                % Input file generated for the specified start node
                inpId = fopen(strcat(app.path,'Input.dat'), 'w');
                for i = 1:app.nodeCount
                    if (i ~= str2double(value))
                        fprintf(inpId, '%s\n%s\nFF\n', ...
                            value, num2str(i));
                    end
                end
                fprintf(inpId, '0\n');
                fclose(inpId);
    
                % Update status window
                app.inputStatus = {['Input.dat for root node ',  value, ...
                    ' generated. Calculating shortest paths...']};
                app.Status.Value = [app.Status.Value; app.inputStatus];

                % Open I/O files for read and write
                inpId = fopen(strcat(app.path,'Input.dat'), 'r');
                outId = fopen(strcat(app.path,'Output.dat'), 'w');
            
                % Read the input file line-by-line
                startNode = (fgetl(inpId));
                while (startNode ~= "0")
                    endNode = (fgetl(inpId));
                    if (strcmp(fgetl(inpId), 'FF'))
                        startNode = str2double(startNode);
                        endNode = str2double(endNode);
                        % Return total path distance and SPT path
                        [dist, SPT] = Dijkstra(app.costMatrix, startNode, endNode);
                        fprintf(outId, '%d\n', dist);
                        fprintf(outId, '%d\n', SPT);
                        fprintf(outId, 'FFFF\n');
                        startNode = (fgetl(inpId));
                    end
                end
                fprintf(outId, '0\n');
                fclose(inpId);
                fclose(outId);
                
                % Output status updates
                app.pathGenStatus = {['Output.dat generated. Click ', ...
                    '''Open Results'' button to see paths.']};
                app.Status.Value = [app.Status.Value; app.pathGenStatus];
                app.OutputBtn.Enable = true;
                
                
                startNode = str2double(value);
                endNode = str2double(app.ManualEnd.Value);
                if (startNode ~= 0 && endNode ~= 0)
                    % Reset graph 
                    app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                        app.plotG.Edges.Weight);
                    % Set color of start and end nodes to black
                    highlight(app.spanPlot, [startNode endNode],'NodeColor','k')
                    % Use MATLAB's shortestpath function to calculate
                    % a shortest path tree (SPT)
                    tree = shortestpath(app.plotG, value, app.ManualEnd.Value);
                    % MATLAB's result will be highlighted in green
                    highlight(app.spanPlot, tree,'EdgeColor','g',...
                        'LineWidth',2);
                    % Recalculation of SPT using Dijkstra for
                    % graphical purposes
                    [~, tree2] = Dijkstra(app.costMatrix, startNode, endNode);
                    % Function's result will be highlighted in yellow                    
                    highlight(app.spanPlot, tree2,'EdgeColor','#EDB120',...
                        'LineWidth',2);
                else
                    % Reset graph 
                    app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                        app.plotG.Edges.Weight);                    
                end
            % Reset graph if start node is 0
            else
                app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                    app.plotG.Edges.Weight);
            end
            
        end

        % Value changed function: RootNode
        function RootNodeValueChanged(app, event)
            value = app.RootNode.Value;
            nodeValue = str2double(value);
            
            % Checks if root node of SPT is not equal to zero
            if (nodeValue ~= 0)
                % Reset graph 
                app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                    app.plotG.Edges.Weight);
                % Calculate SPT 
                tree = shortestpathtree(app.plotG, nodeValue);
                % SPT is highlighted in red
                highlight(app.spanPlot, tree,'EdgeColor','r',...
                    'LineWidth',2);
                % Root node is set to black
                highlight(app.spanPlot, nodeValue,'NodeColor','k')
               
                % Status update
                app.SPTStatus = ['Displaying spanning tree of root node ',...
                    value, '...'];
                app.Status.Value = [app.Status.Value; app.SPTStatus];
            else
                % Reset graph
                app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                    app.plotG.Edges.Weight);
            end
        end

        % Value changed function: ManualEnd
        function ManualEndValueChanged(app, event)
            value = app.ManualEnd.Value;
            
            startNode = str2double(app.ManualStart.Value);
            endNode = str2double(value);
            % Checks the value of the end node
            if (startNode ~= 0 && endNode ~= 0)
                % Reset graph
                app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                    app.plotG.Edges.Weight);
                % Set color of start and end nodes to black
                highlight(app.spanPlot, [startNode endNode],'NodeColor','k')
                % Use MATLAB's shortestpath function to calculate
                % a shortest path tree (SPT) from current start value
                % to new end value
                tree = shortestpath(app.plotG, app.ManualStart.Value, value, 'Method', 'positive');
                % MATLAB's result will be highlighted in green                
                highlight(app.spanPlot, tree,'EdgeColor','g',...
                    'LineWidth',2);
                % Get SPT from Dijkstra function 
                [~, tree2] = Dijkstra(app.costMatrix, startNode, endNode);
                % Function's result will be highlighted in yellow                    
                highlight(app.spanPlot, tree2,'EdgeColor','#EDB120',...
                    'LineWidth',2);                
            else
                % Reset graph
                app.spanPlot = plot(app.STGraphPlot, app.plotG,'Layout','force','EdgeLabel',...
                    app.plotG.Edges.Weight);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShortestPathCalculatorUIFigure = uifigure('Visible', 'off');
            app.ShortestPathCalculatorUIFigure.Name = 'Shortest Path Calculator';
            app.Status = uitextarea(app.ShortestPathCalculatorUIFigure);
            app.Status.Editable = 'off';
            app.InputParametersPanel = uipanel(app.ShortestPathCalculatorUIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SelectthegraphfileLabel = uilabel(app.InputParametersPanel);
            app.SelectthegraphfileLabel.Text = 'Select the graph file:';
            app.GraphBrowse = uibutton(app.InputParametersPanel, 'push');
            app.GraphBrowse.ButtonPushedFcn = createCallbackFcn(app, @GraphBrowseButtonPushed, true);
            app.GraphBrowse.Text = 'Browse';
            app.InputmodeDropDownLabel = uilabel(app.InputParametersPanel);
            app.InputmodeDropDownLabel.Text = 'Input mode:  ';
            app.InputmodeDropDown = uidropdown(app.InputParametersPanel);
            app.InputmodeDropDown.Items = {'Select one', 'Auto', 'Manual'};
            app.InputmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputmodeDropDownValueChanged, true);
            app.InputmodeDropDown.Enable = 'off';
            app.InputmodeDropDown.Value = 'Select one';
            app.RootNodeLabel = uilabel(app.InputParametersPanel);
            app.RootNodeLabel.Visible = 'off';
            app.RootNodeLabel.Text = 'Display SPT Root Node:';
            app.RootNode = uidropdown(app.InputParametersPanel);
            app.RootNode.Items = {};
            app.RootNode.ValueChangedFcn = createCallbackFcn(app, @RootNodeValueChanged, true);
            app.RootNode.Enable = 'off';
            app.RootNode.Visible = 'off';
            app.RootNode.Value = {};
            app.ManualStartNode = uilabel(app.InputParametersPanel);
            app.ManualStartNode.Visible = 'off';
            app.ManualStartNode.Text = 'Start:';
            app.ManualStart = uidropdown(app.InputParametersPanel);
            app.ManualStart.Items = {};
            app.ManualStart.ValueChangedFcn = createCallbackFcn(app, @ManualStartValueChanged, true);
            app.ManualStart.Enable = 'off';
            app.ManualStart.Visible = 'off';
            app.ManualStart.Value = {};
            app.ManualEndNode = uilabel(app.InputParametersPanel);
            app.ManualEndNode.Visible = 'off';
            app.ManualEndNode.Text = 'End:';
            app.ManualEnd = uidropdown(app.InputParametersPanel);
            app.ManualEnd.Items = {};
            app.ManualEnd.ValueChangedFcn = createCallbackFcn(app, @ManualEndValueChanged, true);
            app.ManualEnd.Enable = 'off';
            app.ManualEnd.Visible = 'off';
            app.ManualEnd.Value = {};
            app.OutputBtn = uibutton(app.ShortestPathCalculatorUIFigure, 'push');
            app.OutputBtn.ButtonPushedFcn = createCallbackFcn(app, @OutputBtnPushed, true);
            app.OutputBtn.Enable = 'off';
            app.OutputBtn.Text = 'Open Results';
            app.ExitButton = uibutton(app.ShortestPathCalculatorUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.STGraphPlot = uiaxes(app.ShortestPathCalculatorUIFigure);
            app.STGraphPlot.XAxisLocation = 'origin';
            app.STGraphPlot.XTick = [];
            app.STGraphPlot.XTickLabel = '';
            app.STGraphPlot.YAxisLocation = 'origin';
            app.STGraphPlot.YTick = [];
            app.STGraphPlot.Visible = 'off';
            % Show the figure after all components are created
            app.ShortestPathCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShortestPath

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShortestPathCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShortestPathCalculatorUIFigure)
        end
    end
end